classdef AudioPlayerWithMeter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MainGrid               matlab.ui.container.GridLayout
        PlaybackToolbarGrid    matlab.ui.container.GridLayout
        TimeStatusLabel        matlab.ui.control.Label
        RepeatButton           matlab.ui.control.StateButton
        StopButton             matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        Meter                  audio.ui.control.Meter
        AudioFileBrowseButton  matlab.ui.control.Button
        AudioFileEditField     matlab.ui.control.EditField
        AudioFileLabel         matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Graphics objects
        CursorLine

        % Data objects
        pAudioFileReader
        pAudioPlayer
        pAudioLevelMeter

        % Flags and other runtime data
        AudioFileName (1,1) string
        AudioFileReadPointer (1,1) double = 0
        PlaybackState (1,1) string {mustBeMember(PlaybackState, ...
            ["nodata", "ready", "playing", "paused"])} = "nodata"
        PauseRequested (1,1) logical = false
        StopRequested (1,1) logical = false
        IsRepeatPlayback (1,1) logical = false
    end

    methods (Access = private)
        function setupAxes(app)
            % Add axes toolbar with zooming and panning buttons. Disable
            % axes interactivity for better performance.
            ax = app.UIAxes;
            axtoolbar(ax, {'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(ax);
        end

        function loadAudioFile(app)
            % Load audio data and plot

            % Read audio data from the file
            y = [];
            t = duration(0,0,[]);
            isValidAudioFile = false;
            try
                [y,fs] = audioread(app.AudioFileName);
                t = seconds(0:1/fs:(size(y,1)-1)/fs);
                isValidAudioFile = true;
            catch ME
                uialert(app.UIFigure,ME.message,'Invalid File');
            end

            % Clear the axes and plot the audio data
            ax = app.UIAxes;
            cla(ax);
            plot(ax,t,y);
            xlim(ax,"tight");
            ylim(ax,[-1 1]);
            % Set x-axes tick format
            if isempty(t)
                fmt = 's';
            elseif t(end) < hours(1)
                fmt = 'mm:ss';
            else
                fmt = 'hh:mm:ss';
            end
            xtickformat(ax,fmt);

            % Set up data objects and update app components
            if isValidAudioFile
                % Update the data objects
                app.pAudioFileReader.Filename = app.AudioFileName;
                app.pAudioPlayer.SampleRate = fs;
                app.pAudioLevelMeter.SampleRate = fs;
                % Create a cursor line
                app.CursorLine = line(ax, seconds([0 0]), [-1 1], ...
                    Color=[0.1 0.1 0.1], LineWidth=0.75);
                setPlaybackPosition(app,[]);
                % Update the playback toolbar buttons
                setPlaybackState(app,'ready');
            else
                setPlaybackState(app,'nodata');
            end
        end

        function setPlaybackState(app, playbackState)
            % Update the app components when the playback state changes

            % Disable loading an audio file during playback
            app.AudioFileEditField.Enable = ~ismember(playbackState,{'playing','paused'});
            app.AudioFileBrowseButton.Enable = ~ismember(playbackState,{'playing','paused'});

            % Set playback buttons enableness
            app.PlayButton.Enable = ismember(playbackState,{'ready','playing','paused'});
            app.StopButton.Enable = ismember(playbackState,{'playing','paused'});
            app.RepeatButton.Enable = ismember(playbackState,{'ready'});

            % Set play button mode
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if strcmp(playbackState,'playing')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'media_control_pause_24.png');
                app.PlayButton.Tooltip = 'Pause';
            elseif strcmp(playbackState,'paused')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'media_control_play_24.png');
                app.PlayButton.Tooltip = 'Continue';
            else
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'media_control_play_24.png');
                app.PlayButton.Tooltip = 'Play';
            end

            % Save the playback state and flush the graphics queue
            app.PlaybackState = playbackState;
            drawnow
        end

        function setPlaybackPosition(app,xLoc)
            % Set the playback position by updating the cursor location and
            % the time status
            cursorLine = app.CursorLine;
            if isempty(xLoc)
                cursorLine.Visible = 'off';
                tStr = string(duration(0,0,0,'Format','hh:mm:ss.SSS'));
            else
                set(cursorLine, 'XData', [xLoc,xLoc], 'Visible', 'on');
                xLoc.Format = 'hh:mm:ss.SSS';
                tStr = string(xLoc);
            end
            app.TimeStatusLabel.Text = sprintf('T = %s',tStr);
        end

        function play(app)
            player = app.pAudioPlayer;
            reader = app.pAudioFileReader;
            levelMeter = app.pAudioLevelMeter;
            uimeter = app.Meter;

            % If this is new playback, reset the read pointer
            if app.PlaybackState == "ready"
                app.AudioFileReadPointer = 0;
            end
            fs = reader.SampleRate;
            totalSamples = reader.TotalSamples;
            currPointer = app.AudioFileReadPointer;

            % Start playback
            setPlaybackState(app, 'playing');
            while ~isDone(reader) &&  ~(app.StopRequested || app.PauseRequested)
                % Read audio data and play
                audioIn = reader();
                player(audioIn);
                uimeter.Value = levelMeter(audioIn);

                % Increment read pointer and update cursor position
                currPointer = currPointer + size(audioIn,1);
                currPointer = mod(currPointer,totalSamples);
                setPlaybackPosition(app,seconds(currPointer/fs));

                % Call drawnow to update graphics
                drawnow limitrate
            end
            app.AudioFileReadPointer = currPointer;

            if app.PauseRequested
                setPlaybackState(app, 'paused');
                app.PauseRequested = false;
            else
                stop(app);
                app.StopRequested = false;
            end
        end

        function stop(app)
            release(app.pAudioPlayer);
            release(app.pAudioFileReader);
            release(app.pAudioLevelMeter);
            setPlaybackPosition(app,[]);
            setPlaybackState(app, 'ready');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create model objects
            frameLen = 512;
            app.pAudioFileReader = dsp.AudioFileReader(SamplesPerFrame=frameLen);
            app.pAudioPlayer = audioDeviceWriter;
            app.pAudioLevelMeter = audioLevelMeter(WindowLength=frameLen);
            % Initialize the axes
            setupAxes(app);
            % Load a default audio file
            app.AudioFileName = 'RockDrums-44p1-stereo-11secs.mp3';
            app.AudioFileEditField.Value = app.AudioFileName;
            loadAudioFile(app);
        end

        % Button pushed function: AudioFileBrowseButton
        function AudioFileBrowseButtonPushed(app, event)
            if (exist(app.AudioFileName,'file'))
                currFile = which(app.AudioFileName);
            else
                currFile = app.AudioFileName;
            end

            % Browse for audio files using uigetfile
            dialogTitle = getString(message('dsp:system:AudioFileReader:SelectAudioFile'));
            audioFilesTitle = [getString(message('dsp:system:AudioFileReader:AudioFiles')), ...
                ' (*.wav,*.flac,*.ogg,*.aif,*.mp3 ...)'];
            allFilesTitle = [getString(message('dsp:system:AudioFileReader:AllFiles')),' (*.*)'];
            audioFileExts = dsp.AudioFileReader.getSupportedFileExtensions();
            audioFileFormats = join(strcat('*', audioFileExts(:)),';');
            filterSpec = {audioFileFormats{1}, audioFilesTitle; ...
                '*.*', allFilesTitle};
            [filename, pathname] = uigetfile(filterSpec,dialogTitle,currFile);

            if filename
                % Change the property and update the edit field
                app.AudioFileName = fullfile(pathname,filename);
                app.AudioFileEditField.Value = app.AudioFileName;
                loadAudioFile(app);
            end
        end

        % Value changed function: AudioFileEditField
        function AudioFileEditFieldValueChanged(app, event)
            app.AudioFileName = app.AudioFileEditField.Value;
            loadAudioFile(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                else
                    % A new start or continue previously paused
                    play(app);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during playback');
                stop(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.PlaybackState == "playing"
                app.StopRequested = true;
            else
                stop(app);
            end
        end

        % Value changed function: RepeatButton
        function RepeatButtonValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.RepeatButton.Value
                app.pAudioFileReader.PlayCount = Inf;
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'media_control_repeat_on_24.png');
            else
                app.pAudioFileReader.PlayCount = 1;
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'media_control_repeat_off_24.png');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Audio Player with Meter';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.MainGrid);
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-1 1];
            app.AudioFileLabel = uilabel(app.MainGrid);
            app.AudioFileLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.MainGrid, 'text');
            app.AudioFileEditField.ValueChangedFcn = createCallbackFcn(app, @AudioFileEditFieldValueChanged, true);
            app.AudioFileEditField.Placeholder = 'Enter audio file path';
            app.AudioFileBrowseButton = uibutton(app.MainGrid, 'push');
            app.AudioFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @AudioFileBrowseButtonPushed, true);
            app.AudioFileBrowseButton.Text = 'Browse';
            app.Meter = uiaudiometer(app.MainGrid);
            app.PlaybackToolbarGrid = uigridlayout(app.MainGrid);
            app.PlayButton = uibutton(app.PlaybackToolbarGrid, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'media_control_play_24.png');
            app.PlayButton.Tooltip = {'Play'};
            app.PlayButton.Text = '';
            app.StopButton = uibutton(app.PlaybackToolbarGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'media_control_stop_24.png');
            app.StopButton.Tooltip = {'Stop playing'};
            app.StopButton.Text = '';
            app.RepeatButton = uibutton(app.PlaybackToolbarGrid, 'state');
            app.RepeatButton.ValueChangedFcn = createCallbackFcn(app, @RepeatButtonValueChanged, true);
            app.RepeatButton.Tooltip = {'Play in a loop'};
            app.RepeatButton.Icon = fullfile(pathToMLAPP, 'media_control_repeat_off_24.png');
            app.RepeatButton.Text = '';
            app.TimeStatusLabel = uilabel(app.PlaybackToolbarGrid);
            app.TimeStatusLabel.Text = 'T = 00:00:00.000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioPlayerWithMeter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end