classdef VideoPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        FreeEditingTab         matlab.ui.container.Tab
        UIAxesFreeEditing      matlab.ui.control.UIAxes
        Preview360Tab          matlab.ui.container.Tab
        UIAxes360Preview       matlab.ui.control.UIAxes
        RemoveKeyFrame         matlab.ui.control.Button
        AddKeyFrame            matlab.ui.control.Button
        ExportVideoButton      matlab.ui.control.Button
        KeyFrameViewDropDown   matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        VideoFileBrowseButton  matlab.ui.control.Button
        VideoFileEditField     matlab.ui.control.EditField
        VideoFileLabel         matlab.ui.control.Label
        TimeStatusLabel        matlab.ui.control.Label
        RepeatButton           matlab.ui.control.StateButton
        StopButton             matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        UIAxesProgress         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Graphics objects
        CursorLine % xline function return object type
        SurfaceTexture % surf function return object type
        IsExternTexture (1,1) logical = false % bool类型，外部纹理与内部相反，需要左右翻转
        IsUpdateRot (1,1) logical = false % bool类型，用于控制小行星默认视角
        hTransformObj % hgtransform function return object type
        % 初始化状态变量，鼠标交互用到的变量
        prev_pt = [];
        rotation = eye(3);

        % 纹理创建时候的x,y,z坐标数据
        XData
        YData
        ZData

        % Data objects
        pVideoFileReader
        tEnd % video duration，单位：秒，类型：duration

        % Key Frame
        % KeyFrameItem = struct("timestamp",[],... % unit:秒
        %     "scatterObj",[],...
        %     "cameraPosition",[],...
        %     "cameraTarget",[],...
        %     "cameraUpvector",[],...
        %     "cameraRotation",app.rotation,... % 3*3矩阵
        %     "cameraFOV",[]); % 单位：度
        KeyFrames = configureDictionary("string","struct") % 关键帧的时间戳和相机参数，相机参数与上述的KeyFrameItem struct对应，since R2023b
        outputFrames = configureDictionary("double","struct") % 插值输出每帧序号和相机参数

        % Flags and other runtime data
        VideoFileName (1,1) string
        VideoFileReadPointer (1,1) double = 0
        PlaybackState (1,1) string {mustBeMember(PlaybackState, ...
            ["nodata", "ready", "playing", "paused"])} = "nodata"
        PauseRequested (1,1) logical = false
        StopRequested (1,1) logical = false
    end

    methods (Access = private)
        function setupAxes(app)
            % Disable axes interactivity for better performance.
            axFreeEditing = app.UIAxesFreeEditing;
            disableDefaultInteractivity(axFreeEditing);

            % uiaxes坐标范围归一化到容器内，默认与axes不一样！
            axFreeEditing.Units = "normalized";
            axFreeEditing.Position = [0,0,1,1];

            % 窗口默认工具条隐藏和设置相机初始化视角参数
            axFreeEditing.Toolbar.Visible = 'off';
            axFreeEditing.DataAspectRatio = [1,1,1];
           
            axFreeEditing.Projection = 'perspective';
            axFreeEditing.Visible='off';

            axis(axFreeEditing,"tight")
            axis(axFreeEditing,"off")

            % 360 equirectangular 图像预览轴
            ax360Preview = app.UIAxes360Preview;
            axtoolbar(ax360Preview, {'zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(ax360Preview);
            axis(ax360Preview,"tight")
            axis(ax360Preview,"off")

            % 进度轴
            axProgress = app.UIAxesProgress;
            disableDefaultInteractivity(axProgress);
            axProgress.XGrid="on";
            axProgress.Box = "on";
            % axProgress.DataAspectRatio = [1,1,1];
            hold(axProgress,"on");
        end

        function setupCamera(app)
            % 初始化默认值
            setCameraParams(app,"perspective");
        end

        function setCameraParams(app,viewModeName)
            ax = app.UIAxesFreeEditing;
             % 根据下拉列表执行模式变换
            switch viewModeName
                case "perspective"
                    ax.CameraPosition = [0,0,0];
                    ax.CameraTarget = [1,0,0];
                    ax.CameraUpVector = [0,0,-1];
                    ax.CameraViewAngle = 60;
                    app.IsExternTexture = false;
                case "littleplanet"
                    ax.CameraPosition = [-1,0,0];
                    ax.CameraTarget = [0,0,0];
                    ax.CameraUpVector = [0,0,-1];
                    ax.CameraViewAngle = 120;
                    app.IsExternTexture = false;
                case "crystalball"
                    ax.CameraPosition = [2,0,0];
                    ax.CameraTarget = [0,0,0];
                    ax.CameraUpVector = [0,0,-1];
                    ax.CameraViewAngle = 60;
                    app.IsExternTexture = true;
                otherwise
                    uialert(app.UIFigure, ME.message, 'Error during view mode');
                        stop(app);
            end

            % 或者是重置摄像机为某个模式
            if app.KeyFrameViewDropDown.Value~=viewModeName
                app.KeyFrameViewDropDown.Value = viewModeName;
            end
        end

        function createSurfTexture(app,frame)
            axFreeEditing = app.UIAxesFreeEditing;
            [X, Y, Z] = sphere(50);
            frame = fliplr(frame);
            app.SurfaceTexture = surf(axFreeEditing, X, Y, Z,frame,'FaceColor','texture','EdgeColor','none');
            app.XData = app.SurfaceTexture.XData;
            app.YData = app.SurfaceTexture.YData;
            app.ZData = app.SurfaceTexture.ZData;

            % 初始化转换对象
            app.hTransformObj = hgtransform(axFreeEditing);
            app.SurfaceTexture.Parent = app.hTransformObj;
            warning off;
            app.rotation = eye(3);
        end

        function setSurfTexture(app,frame)
            % 设置纹理，球体内外纹理图像左右对称
            if app.IsExternTexture
                app.SurfaceTexture.CData = frame;
            else
                app.SurfaceTexture.CData = fliplr(frame);
            end
        end

        function loadVideoFile(app)
            % Load video data 
            isValidVideoFIle = false;
            try
                app.pVideoFileReader = VideoReader(app.VideoFileName);
                isValidVideoFIle = true;
            catch ME
                uialert(app.UIFigure,ME.message,'Invalid File');
            end

            % Clear the axes and show first frame
            ax360preview = app.UIAxes360Preview;

            % Set up data objects and update app components
            if isValidVideoFIle
                clearKeyFrames(app);
                clearOutputFrames(app);
                frame = readFrame(app.pVideoFileReader);
                image(ax360preview,frame);
                createSurfTexture(app,frame);
                setupCamera(app);

                app.tEnd =  seconds(app.pVideoFileReader.Duration);
                app.tEnd.Format = 'hh:mm:ss';
                app.Slider.Limits = [0,app.pVideoFileReader.Duration+1];
                app.UIAxesProgress.XLim = [0,app.pVideoFileReader.Duration+1];
                app.UIAxesProgress.XTick = linspace(0,app.pVideoFileReader.Duration+1,100);
                if isempty(app.CursorLine)
                    app.CursorLine = xline(app.UIAxesProgress,0,Color="red",LineWidth=2);
                end

                setPlaybackPosition(app,[]);
                % Update the playback toolbar buttons
                setPlaybackState(app,'ready');
            else
                setPlaybackState(app,'nodata');
            end
        end

        function setPlaybackState(app, playbackState)
            % Update the app components when the playback state changes

            % Disable loading an video file during playback
            app.VideoFileEditField.Enable = ~ismember(playbackState,{'playing','paused'});
            app.VideoFileBrowseButton.Enable = ~ismember(playbackState,{'playing','paused'});

            % Set playback buttons enableness
            app.PlayButton.Enable = ismember(playbackState,{'ready','playing','paused'});
            app.StopButton.Enable = ismember(playbackState,{'playing','paused'});
            app.RepeatButton.Enable = ismember(playbackState,{'ready'});

            % Set manpulate buttons enabless
            app.KeyFrameViewDropDown.Enable = ismember(playbackState,{'ready','paused'});
            app.AddKeyFrame.Enable = ismember(playbackState,{'ready','paused'});
            app.RemoveKeyFrame.Enable = false;
            app.ExportVideoButton.Enable = ismember(playbackState,{'ready','paused'});

            % Set play button mode
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if strcmp(playbackState,'playing')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_pause_24.png');
                app.PlayButton.Tooltip = 'Pause';
            elseif strcmp(playbackState,'paused')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_play_24.png');
                app.PlayButton.Tooltip = 'Continue';
            else
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_play_24.png');
                app.PlayButton.Tooltip = 'Play';
            end

            % Save the playback state and flush the graphics queue
            app.PlaybackState = playbackState;
            drawnow
        end

        function setPlaybackPosition(app,xLoc)
            % Set the playback position by updating the cursor location and
            % the time status,xLoc type is duration
            if isempty(xLoc)
                app.Slider.Value = 0;
                app.CursorLine.Value = 0;
                tStr = string(duration(0,0,0,'Format','hh:mm:ss'));
            else
                app.Slider.Value = seconds(xLoc);
                app.CursorLine.Value = seconds(xLoc);
                xLoc.Format = 'hh:mm:ss';
                tStr = string(xLoc);
            end
            
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',tStr,string(app.tEnd));
        end

        function setVideoPlaybackPosition(app,xLoc)
            % xLoc type is double
            reader = app.pVideoFileReader;
            reader.CurrentTime = min(xLoc,app.pVideoFileReader.Duration);
            if hasFrame(reader)
                frame = readFrame(app.pVideoFileReader);
                image(app.UIAxes360Preview,frame);

                numFrames = reader.numFrames;
                if numEntries(app.outputFrames)
                    idxFrame = round(numFrames*reader.CurrentTime/reader.Duration);
                    idxFrame = min(idxFrame,numFrames);
                    app.UIAxesFreeEditing.CameraPosition = app.outputFrames(idxFrame).posQuery;
                    app.UIAxesFreeEditing.CameraViewAngle = app.outputFrames(idxFrame).fovQuery;
                    app.rotation = app.outputFrames(idxFrame).rotmQuery;
                    if isfield(app.outputFrames(idxFrame),"maskCoord")
                        mask = app.outputFrames(idxFrame).maskCoord;
                        app.SurfaceTexture.XData(mask) = app.outputFrames(idxFrame).xCoord;
                        app.SurfaceTexture.YData(mask) = app.outputFrames(idxFrame).yCoord;
                        app.SurfaceTexture.ZData(mask) = app.outputFrames(idxFrame).zCoord;
                        % app.UIAxesFreeEditing.CameraTarget = app.outputFrames(idxFrame).cameraTarget;
                        % app.UIAxesFreeEditing.CameraUpVector = app.outputFrames(idxFrame).cameraUpVector;
                    end 

                    if abs(app.UIAxesFreeEditing.CameraPosition(1))>1
                        app.IsExternTexture = true;
                    else
                        app.IsExternTexture = false;
                    end
                end

                % 更新图形
                setSurfTexture(app,frame);
                app.hTransformObj.Matrix(1:3,1:3) = app.rotation;
            end
        end

        function play(app)
            reader = app.pVideoFileReader;
            axFreeEditing = app.UIAxesFreeEditing;
            ax360preview = app.UIAxes360Preview;
            numFrames = reader.numFrames;

            % Start playback
            setPlaybackState(app, 'playing');
            while hasFrame(reader) &&  ~(app.StopRequested || app.PauseRequested)
                % Read video data and play
                frame = readFrame(reader);
                image(ax360preview,frame);
                % app.SurfaceTexture.CData = frame;

                % 插值帧的相机参数
                if numEntries(app.outputFrames)
                    idxFrame = round(numFrames*reader.CurrentTime/app.pVideoFileReader.Duration);
                    idxFrame = min(idxFrame,numFrames);
                    axFreeEditing.CameraPosition = app.outputFrames(idxFrame).posQuery;
                    axFreeEditing.CameraViewAngle = app.outputFrames(idxFrame).fovQuery;
                    app.rotation = app.outputFrames(idxFrame).rotmQuery;

                    if isfield(app.outputFrames(idxFrame),"maskCoord")
                        mask = app.outputFrames(idxFrame).maskCoord;
                        xc = app.outputFrames(idxFrame).xCoord;
                        yc = app.outputFrames(idxFrame).yCoord;
                        zc = app.outputFrames(idxFrame).zCoord;

                        app.SurfaceTexture.XData(mask) = xc;
                        app.SurfaceTexture.YData(mask) = yc;
                        app.SurfaceTexture.ZData(mask) = zc;
                    end

                    if abs(axFreeEditing.CameraPosition(1))>1
                        app.IsExternTexture = true;
                    else
                        app.IsExternTexture = false;
                    end
                end

                % 更新图形
                setSurfTexture(app,frame);
                app.hTransformObj.Matrix(1:3,1:3) = app.rotation;
                
                setPlaybackPosition(app,seconds(reader.CurrentTime));

                % Call drawnow to update graphics
                % drawnow %limitrate
                pause(1/reader.FrameRate);
            end

            if app.PauseRequested
                setPlaybackState(app, 'paused');
                app.PauseRequested = false;
            elseif app.StopRequested
                stop(app);
                app.StopRequested = false;
            else
                if app.RepeatButton.Value
                    app.pVideoFileReader.CurrentTime =0;
                    play(app);
                else
                    stop(app);
                    app.StopRequested = false;
                end
            end
        end

        function stop(app)
            app.pVideoFileReader.CurrentTime=0;
            frame = readFrame(app.pVideoFileReader);
            image(app.UIAxesFreeEditing,frame);
            createSurfTexture(app,frame);
            setupCamera(app)

            setPlaybackPosition(app,[]);
            setPlaybackState(app, 'ready');
        end


        function R = rotx(~,deg)
            R = [1,0,0;
                0,cosd(deg),-sind(deg);
                0,sind(deg),cosd(deg)];
        end
        function R = roty(~,deg)
            R = [cosd(deg),0,sind(deg);
                0,1,0;
                -sind(deg),0,cosd(deg)];
        end
        function R = rotz(~,deg)
            R = [cosd(deg),-sind(deg),0;
                sind(deg),cosd(deg),0;
                0,0,1];
        end


        function clearKeyFrames(app)
            numKeyFrames = numEntries(app.KeyFrames);
            if numKeyFrames
                keysFrameStr = keys(app.KeyFrames);
                for idx = 1:numKeyFrames
                    delete(app.KeyFrames(keysFrameStr(idx)).scatterObj);
                    app.KeyFrames(keysFrameStr(idx)) = [];
                end
            end
        end

        function clearOutputFrames(app)
            numKeyFrames = numEntries(app.outputFrames);
            if numKeyFrames
                keysFrameDouble = keys(app.outputFrames);
                for idx = 1:numKeyFrames
                    app.outputFrames(keysFrameDouble(idx)) = [];
                end
                setCameraParams(app,app.KeyFrameViewDropDown.Value);
            end
        end
        
        function updateCameraParams(app)
            % 根据app.KeyFrames更新视频中摄像机每帧的位姿,使用四元数球面线性插值算法SLERP
            % 
            % 第一帧人为设定为透视无畸变图（如果用户没有设置），最后一帧与最后一个样本点保持一致
            % 
            % 参考相机参数
            % KeyFrameItem = struct("timestamp",[],... % unit:秒
            %     "scatterObj",[],...
            %     "cameraPosition",[],...
            %     "cameraTarget",[],...
            %     "cameraUpvector",[],...
            %     "cameraFOV",[]); % 单位：度
            %
            % see also:
            %    slerp
            %
            numVideoFrames = app.pVideoFileReader.NumFrames;
            numKeyFrames = numEntries(app.KeyFrames);
            if numKeyFrames
                keysFrameStr = keys(app.KeyFrames);  % N*1 string array
                valuesFrame = values(app.KeyFrames); % N*1 struct array
                keysFrame = double(keysFrameStr);
                [~,idxs] = sort(keysFrame);
                valuesFrame = valuesFrame(idxs);% N*1 struct array,ordered
                for idx = 1:numKeyFrames+1 % 区间段,N个点分N+1个区间段
                    % 起止帧号
                    if idx==1
                        idxStartFrame = 1;
                    else
                        idxStartFrame = round(numVideoFrames*valuesFrame(idx-1).timestamp/seconds(app.tEnd));
                        idxStartFrame = min(max(1,idxStartFrame),numVideoFrames);
                    end

                    if idx == numKeyFrames+1
                        idxEndFrame = numVideoFrames;
                    else
                        idxEndFrame = round(numVideoFrames*valuesFrame(idx).timestamp/seconds(app.tEnd));
                        idxEndFrame = min(max(1,idxEndFrame),numVideoFrames);
                    end

                    % 起止相机参数
                    if idx==1&&idxEndFrame==1
                        cameraPosition1 = valuesFrame(idx).cameraPosition;
                        cameraRotation1 = valuesFrame(idx).cameraRotation;
                        cameraFOV1 = valuesFrame(idx).cameraFOV;

                        cameraPosition2 =cameraPosition1;
                        cameraRotation2 = cameraRotation1;
                        cameraFOV2 = cameraFOV1;
                    elseif idx==1&&idxEndFrame~=1
                        cameraPosition1 = [0,0,0];
                        cameraRotation1 = eye(3);
                        cameraFOV1 = 60;

                        cameraPosition2 = valuesFrame(idx).cameraPosition;
                        cameraRotation2 =  valuesFrame(idx).cameraRotation;
                        cameraFOV2 = valuesFrame(idx).cameraFOV;
                    elseif idx== numKeyFrames+1
                        cameraPosition1 = valuesFrame(idx-1).cameraPosition;
                        cameraRotation1 = valuesFrame(idx-1).cameraRotation;
                        cameraFOV1 = valuesFrame(idx-1).cameraFOV;

                        cameraPosition2 =cameraPosition1;
                        cameraRotation2 = cameraRotation1;
                        cameraFOV2 = cameraFOV1;
                    else
                        cameraPosition1 = valuesFrame(idx-1).cameraPosition;
                        cameraRotation1 =  valuesFrame(idx-1).cameraRotation;
                        cameraFOV1 = valuesFrame(idx-1).cameraFOV;

                        cameraPosition2 =valuesFrame(idx).cameraPosition;
                        cameraRotation2 =  valuesFrame(idx).cameraRotation;
                        cameraFOV2 = valuesFrame(idx).cameraFOV;
                    end

                    % 插值
                    iFrame = [idxStartFrame;idxEndFrame];
                    iPosition = [cameraPosition1;cameraPosition2];
                    iFOV = [cameraFOV1;cameraFOV2];

                    FrameQuerys = (idxStartFrame:idxEndFrame)';
                    fovQuerys = interp1(iFrame,iFOV,FrameQuerys,"linear");

                    q1 = quaternion(cameraRotation1,"rotmat","frame");
                    q2 = quaternion(cameraRotation2,"rotmat","frame");
                    T = linspace(0,1,idxEndFrame-idxStartFrame+1)';
                    quatsQuerys = slerp(q1,q2,T);
                    rotmQuerys = rotmat(quatsQuerys,"frame");% 3*3*N

                    % 单位球内外插值
                    if cameraPosition1(1)==0&&cameraPosition2(1)>1 % 透视转水晶球
                        % 记录保存
                        % https://www.mathworks.com/matlabcentral/discussions/tips/875520-an-efficient-way-to-create-struct-arrays
                        camerapara = struct("posQuery",[],...
                            "fovQuery",[],...
                            "rotmQuery",[],...
                            "maskCoord",[],...
                            "xCoord",[],...
                            "yCoord",[],...
                            "zCoord",[]);
                        k = linspace(0.01,1,numel(FrameQuerys)-1); % 曲率
                        radius = 1./k;
                        maskCoord = app.XData>cosd(180/2);
                        XDataOri = app.XData(maskCoord);
                        YDataOri = app.YData(maskCoord);
                        ZDataOri = app.ZData(maskCoord);
                        theta = acosd(XDataOri);
                        for itemIdx = 1:numel(FrameQuerys)-1
                            r = radius(itemIdx);
                            % 根据"mathForPerspectiveAndFisheyeTransformation.mlx"公式计算
                            x = cosd(theta).*(cosd(theta) + sqrt(r.^2.*cosd(theta).^2 - 2*r.*cosd(theta).^2 + 2*r + cosd(theta).^2 - 1) - r.*cosd(theta));
                            y = (YDataOri.*x)./XDataOri;
                            z = (ZDataOri.*x)./XDataOri;

                            camerapara(itemIdx) = struct("posQuery",[2,0,0],...
                                "fovQuery",fovQuerys(itemIdx),...
                                "rotmQuery",rotmQuerys(:,:,itemIdx),...
                                "maskCoord",maskCoord,...
                                "xCoord",x,...
                                "yCoord",y,...
                                "zCoord",z);
                        end
                        camerapara(itemIdx+1) = struct("posQuery",[2,0,0],...
                            "fovQuery",fovQuerys(itemIdx+1),...
                            "rotmQuery",rotmQuerys(:,:,itemIdx+1),...
                            "maskCoord",maskCoord,...
                            "xCoord",x,...
                            "yCoord",y,...
                            "zCoord",z);

                    elseif cameraPosition1(1)<0&&cameraPosition2(1)>1 % 小行星转水晶球
                        camerapara = struct("posQuery",[],...
                            "fovQuery",[],...
                            "rotmQuery",[],...
                            "maskCoord",[],...
                            "xCoord",[],...
                            "yCoord",[],...
                            "zCoord",[]);
                        k = logspace(-2,0,numel(FrameQuerys)-1);% 对数变化
                        radius = 1./k;
                        maskCoord = app.XData>cosd(180/2);
                        XDataOri = app.XData(maskCoord);
                        YDataOri = app.YData(maskCoord);
                        ZDataOri = app.ZData(maskCoord);

                        norma = sqrt((XDataOri+1).^2+YDataOri.^2+ZDataOri.^2);
                        normb = 1;
                        theta = acosd((XDataOri+1)./(norma*normb));

                        for itemIdx = 1:numel(FrameQuerys)-1
                            r = radius(itemIdx);
                            % 根据"mathForPerspectiveAndFisheyeTransformation.mlx"公式计算
                            x = cosd(theta).*(2*cosd(theta) + sqrt(r.^2.*cosd(theta).^2 - 4*r.*cosd(theta).^2 + 4*r + 4*cosd(theta).^2 - 4) - r.*cosd(theta)) - 1;
                            y = (YDataOri.*(x+1))./(XDataOri+1);
                            z = (ZDataOri.*(x+1))./(XDataOri+1);

                            camerapara(itemIdx) = struct("posQuery",[2,0,0],...
                                "fovQuery",fovQuerys(itemIdx),...
                                "rotmQuery",rotmQuerys(:,:,itemIdx),...
                                "maskCoord",maskCoord,...
                                "xCoord",x,...
                                "yCoord",y,...
                                "zCoord",z);
                        end
                        camerapara(itemIdx+1) = struct("posQuery",[2,0,0],...
                            "fovQuery",fovQuerys(itemIdx+1),...
                            "rotmQuery",rotmQuerys(:,:,itemIdx+1),...
                            "maskCoord",maskCoord,...
                            "xCoord",x,...
                            "yCoord",y,...
                            "zCoord",z);

                    elseif cameraPosition1(1)>1&&cameraPosition2(1)==0 % 水晶球转透视
                        camerapara = struct("posQuery",[],...
                            "fovQuery",[],...
                            "rotmQuery",[],...
                            "maskCoord",[],...
                            "xCoord",[],...
                            "yCoord",[],...
                            "zCoord",[]);
                        k = linspace(1,0.01,numel(FrameQuerys)-1); % 曲率
                        radius = 1./k;
                        maskCoord = app.XData>cosd(180/2);
                        XDataOri = app.XData(maskCoord);
                        YDataOri = app.YData(maskCoord);
                        ZDataOri = app.ZData(maskCoord);
                        theta = acosd(XDataOri);
                        for itemIdx = 1:numel(FrameQuerys)-1
                            r = radius(itemIdx);
                            x = cosd(theta).*(cosd(theta) + sqrt(r.^2.*cosd(theta).^2 - 2*r.*cosd(theta).^2 + 2*r + cosd(theta).^2 - 1) - r.*cosd(theta));
                            y = (YDataOri.*x)./XDataOri;
                            z = (ZDataOri.*x)./XDataOri;

                            camerapara(itemIdx) = struct("posQuery",[2,0,0],...
                                "fovQuery",fovQuerys(itemIdx),...
                                "rotmQuery",rotmQuerys(:,:,itemIdx),...
                                "maskCoord",maskCoord,...
                                "xCoord",x,...
                                "yCoord",y,...
                                "zCoord",z);
                        end
                        camerapara(itemIdx) = struct("posQuery",[0,0,0],...
                            "fovQuery",fovQuerys(itemIdx),...
                            "rotmQuery",rotmQuerys(:,:,itemIdx),...
                            "maskCoord",maskCoord,...
                            "xCoord",XDataOri,...
                            "yCoord",YDataOri,...
                            "zCoord",ZDataOri);
                        camerapara(itemIdx+1) = struct("posQuery",[0,0,0],...
                            "fovQuery",fovQuerys(itemIdx+1),...
                            "rotmQuery",rotmQuerys(:,:,itemIdx+1),...
                            "maskCoord",maskCoord,...
                            "xCoord",XDataOri,...
                            "yCoord",YDataOri,...
                            "zCoord",ZDataOri);

                    elseif cameraPosition1(1)>1&&cameraPosition2(1)<0 % 水晶球转小行星
                        camerapara = struct("posQuery",[],...
                            "fovQuery",[],...
                            "rotmQuery",[],...
                            "maskCoord",[],...
                            "xCoord",[],...
                            "yCoord",[],...
                            "zCoord",[]);
                        k = logspace(0,-2,numel(FrameQuerys)-1);
                        radius = 1./k;
                        maskCoord = app.XData>cosd(180/2);
                        XDataOri = app.XData(maskCoord);
                        YDataOri = app.YData(maskCoord);
                        ZDataOri = app.ZData(maskCoord);

                        norma = sqrt((XDataOri+1).^2+YDataOri.^2+ZDataOri.^2);
                        normb = 1;
                        theta = acosd((XDataOri+1)./(norma*normb));

                        for itemIdx = 1:numel(FrameQuerys)-1
                            r = radius(itemIdx);
                            % 根据"mathForPerspectiveAndFisheyeTransformation.mlx"公式计算
                            x = cosd(theta).*(2*cosd(theta) + sqrt(r.^2.*cosd(theta).^2 - 4*r.*cosd(theta).^2 + 4*r + 4*cosd(theta).^2 - 4) - r.*cosd(theta)) - 1;
                            y = (YDataOri.*(x+1))./(XDataOri+1);
                            z = (ZDataOri.*(x+1))./(XDataOri+1);

                            camerapara(itemIdx) = struct("posQuery",[2,0,0],...
                                "fovQuery",fovQuerys(itemIdx),...
                                "rotmQuery",rotmQuerys(:,:,itemIdx),...
                                "maskCoord",maskCoord,...
                                "xCoord",x,...
                                "yCoord",y,...
                                "zCoord",z);
                        end
                        camerapara(itemIdx) = struct("posQuery",[-1,0,0],...
                            "fovQuery",fovQuerys(itemIdx),...
                            "rotmQuery",rotmQuerys(:,:,itemIdx),...
                            "maskCoord",maskCoord,...
                            "xCoord",XDataOri,...
                            "yCoord",YDataOri,...
                            "zCoord",ZDataOri);
                        camerapara(itemIdx+1) = struct("posQuery",[-1,0,0],...
                            "fovQuery",fovQuerys(itemIdx+1),...
                            "rotmQuery",rotmQuerys(:,:,itemIdx+1),...
                            "maskCoord",maskCoord,...
                            "xCoord",XDataOri,...
                            "yCoord",YDataOri,...
                            "zCoord",ZDataOri);
                    else
                        posQuerys = interp1(iFrame,iPosition,FrameQuerys,"linear");
                        % 记录保存
                        % https://www.mathworks.com/matlabcentral/discussions/tips/875520-an-efficient-way-to-create-struct-arrays
                        camerapara = struct("posQuery",[],...
                            "fovQuery",[],...
                            "rotmQuery",[]);
                        for itemIdx = length(FrameQuerys):-1:1
                            camerapara(itemIdx) = struct("posQuery",posQuerys(itemIdx,:),...
                                "fovQuery",fovQuerys(itemIdx),...
                                "rotmQuery",rotmQuerys(:,:,itemIdx));
                        end
                    end
                    camerapara = camerapara(:);
                    app.outputFrames = insert(app.outputFrames,FrameQuerys,camerapara);
                end
            else
                clearOutputFrames(app);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create model objects
            setupAxes(app);
            % Load a default video file
            app.VideoFileName = './Assets/360panodemo.mp4';
            app.VideoFileEditField.Value = app.VideoFileName;
            loadVideoFile(app);
            setupCamera(app);
        end

        % Button pushed function: VideoFileBrowseButton
        function VideoFileBrowseButtonPushed(app, event)
            if (exist(app.VideoFileName,'file'))
                currFile = which(app.VideoFileName);
            else
                currFile = app.VideoFileName;
            end

            % Browse for video files using uigetfile
            dialogTitle = "Select Input Video File";
            filterSpec = {'*.mp4';'*.avi'};
            [filename, pathname] = uigetfile(filterSpec,dialogTitle,currFile);

            if filename
                % Change the property and update the edit field
                app.VideoFileName = fullfile(pathname,filename);
                app.VideoFileEditField.Value = app.VideoFileName;
                loadVideoFile(app);
                setupCamera(app);
            end
        end

        % Value changed function: VideoFileEditField
        function VideoFileEditFieldValueChanged(app, event)
            app.VideoFileName = app.VideoFileEditField.Value;
            loadVideoFile(app);
            setupCamera(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                else
                    % A new start or continue previously paused
                    play(app);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during playback');
                stop(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.PlaybackState == "playing"
                app.StopRequested = true;
            else
                stop(app);
            end
        end

        % Value changed function: RepeatButton
        function RepeatButtonValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.RepeatButton.Value
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets','media_control_repeat_on_24.png');
            else
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets','media_control_repeat_off_24.png');
            end
        end

        % Value changing function: Slider
        function progressChangingSliderFcn(app, event)
            % 如果是播放状态，就应该立即暂停
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during progressChangingSliderFcn');
                stop(app);
            end

            changingValue = event.Value;
            app.CursorLine.Value = changingValue;
            setVideoPlaybackPosition(app,changingValue);
            % app.KeyFrameViewDropDown.Enable = false;
            app.RemoveKeyFrame.Enable = false;

            tStr = seconds(changingValue);
            tStr.Format = "hh:mm:ss";
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',string(tStr),string(app.tEnd));

           % drawnow;% limitrate
        end

        % Value changed function: Slider
        function progressChangedFcn(app, event)
            % value = app.Slider.Value;
            value = event.Value;
            tStr = seconds(value);
            tStr.Format = "hh:mm:ss";
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',string(tStr),string(app.tEnd));

        end

        % Window button down function: UIFigure
        function mouseDownCallback(app, event)
            % 鼠标按键按下
            app.prev_pt = get(app.UIFigure, 'CurrentPoint');
        end

        % Window button up function: UIFigure
        function mouseUpCallback(app, event)
            % 鼠标按键释放
            app.prev_pt = [];
        end

        % Window button motion function: UIFigure
        function mouseMoveCallback(app, event)
            % 鼠标拖曳
            if isempty(app.prev_pt)
                return;
            end

            curr_pt = get(app.UIFigure, 'CurrentPoint');
            delta = curr_pt - app.prev_pt;

            % 计算旋转角度
            delataLon = -delta(1, 1)*0.1;
            delataLat = -delta(1, 2)*0.1;

            % 更新旋转矩阵
            app.rotation =  rotz(app,delataLon)*roty(app,delataLat) * app.rotation;

            % 更新图形
            app.hTransformObj.Matrix(1:3,1:3) = app.rotation;
            app.prev_pt = curr_pt;
        end

        % Window scroll wheel function: UIFigure
        function scrollWheelCallback(app, event)
            % 鼠标滚轮缩放
            zoom_factor = 1 - event.VerticalScrollCount * 0.1;
            camzoom(app.UIAxesFreeEditing, zoom_factor);
        end

        % Button pushed function: AddKeyFrame
        function addKeyFramePushed(app, event)
            % 如果是播放状态，就应该立即暂停
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during addKeyFramePushed');
                stop(app);
            end

            xValue = app.Slider.Value;
            timeStamp = app.pVideoFileReader.CurrentTime;% unit:秒
            if isKey(app.KeyFrames,string(timeStamp))
                uialert(app.UIFigure,"Do'nt add duplicated Key Frame","Duplicated Key Frame")
                return; % 避免在一个时间点上重复添加关键帧
            end

            color = [0 0.4470 0.7410];
            scatterObj = scatter(app.UIAxesProgress,xValue,1,180,color,"filled","o");
            % https://ww2.mathworks.cn/matlabcentral/answers/508598-how-do-i-use-the-buttondownfcn-for-a-plot-in-app-designer
            viewModeName = app.KeyFrameViewDropDown.Value;
            scatterObj.ButtonDownFcn = @(src,event)selectKeyFrame(src,event,timeStamp,viewModeName);
            
            % 根据下拉列表执行模式变换
            setCameraParams(app,app.KeyFrameViewDropDown.Value);
             
            % 记录关键帧
            keyFrameItem = struct("timestamp",timeStamp,...
                "scatterObj",scatterObj,...
                "cameraPosition",app.UIAxesFreeEditing.CameraPosition,...
                "cameraTarget",app.UIAxesFreeEditing.CameraTarget,... % 注意，只有app.rotation有用
                "cameraUpvector",app.UIAxesFreeEditing.CameraUpVector,...% 注意，只有app.rotation有用
                "cameraRotation",app.rotation,...
                "cameraFOV",app.UIAxesFreeEditing.CameraViewAngle); % 单位：度
            app.KeyFrames = insert(app.KeyFrames,string(timeStamp),keyFrameItem);

            % 更新整个视频摄像机姿态，SLERP算法插值每帧数据
            updateCameraParams(app);

            function selectKeyFrame(src,~,timeStamp,viewModeName)
                src.Selected= ~src.Selected;
                if src.Selected 
                    % 如果是播放状态，就应该立即暂停
                    try
                        if app.PlaybackState == "playing"
                            app.PauseRequested = true;
                        end
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Error during addKeyFramePushed');
                        stop(app);
                    end
                    
                    % 每次确保只能选中一个,其它全部设置没选中
                    keysFrameStr = keys(app.KeyFrames);
                    for idx = 1:numEntries(app.KeyFrames)
                        key = string(timeStamp);
                        currKey = keysFrameStr(idx);
                        if currKey~=key
                            app.KeyFrames(currKey).scatterObj.Selected = false;
                        end
                    end  

                    % 播放进度定位到此处
                    setPlaybackPosition(app,seconds(src.XData));
                    setVideoPlaybackPosition(app,src.XData);
                    
                    % 显示下拉条选中的状态
                    app.KeyFrameViewDropDown.Value = viewModeName;
                end

                app.KeyFrameViewDropDown.Enable = src.Selected;
                % app.AddKeyFrame.Enable=~src.Selected;
                app.RemoveKeyFrame.Enable = src.Selected;

                % dtt = src.DataTipTemplate;
                % dtRows = [dataTipTextRow("timestamp:",app.pVideoFileReader.CurrentTime),...
                % dataTipTextRow("cameraPosition",app.UIAxesFreeEditing.CameraPosition)];
                % src.DataTipTemplate.DataTipRows(:) = [];
                % src.DataTipTemplate.DataTipRows(1:2) = dtRows;
            end
        end

        % Value changed function: KeyFrameViewDropDown
        function viewModeChanged(app, event)
            % 只有选中的关键帧才可以切换视图
            value = app.KeyFrameViewDropDown.Value;
            setCameraParams(app,value);
        end

        % Button pushed function: RemoveKeyFrame
        function RemoveKeyFramePushed(app, event)
            % 只有选中的关键帧才可以删除
            keyFrames = app.KeyFrames;
            keysFrameStr = keys(keyFrames);
            for idx = 1:numEntries(keyFrames)
                if keyFrames(keysFrameStr(idx)).scatterObj.Selected
                    delete(keyFrames(keysFrameStr(idx)).scatterObj);
                    app.KeyFrames(keysFrameStr(idx)) = [];
                end
            end
            app.RemoveKeyFrame.Enable= false;

            % 更新整个视频摄像机位姿，SLERP算法应用每帧
            updateCameraParams(app);
        end

        % Button pushed function: ExportVideoButton
        function exportVideoFcn(app, event)
            % 非播放状态才可以导出"平面"特效视频
            stop(app);

            % 重新再播放一次，截图存档
            reader = app.pVideoFileReader;
            g = groot;
            sz = g.get("ScreenSize");% [x,y,width,height]，最大屏幕尺寸像素
            fig = uifigure(Position=sz,Visible="on");
            ax = uiaxes(fig,Position=sz);
             disableDefaultInteractivity(ax);

            % uiaxes坐标范围归一化到容器内，默认与axes不一样！
            ax.Units = "normalized";
            ax.Position = [0,0,1,1];

            % 窗口默认工具条隐藏和设置相机初始化视角参数
            ax.Toolbar.Visible = 'on';
            ax.DataAspectRatio = [1,1,1];
           
            ax.Projection = 'perspective';
            ax.Visible='off';

            axis(ax,"tight")
            axis(ax,"off")

            % 定义输出文件
            t = datetime;
            t.Format = "yyyy_MM_dd_HH_mm_ss";
            v = VideoWriter(string(t)+".mp4","MPEG-4");% H264编码的
            outSize = [1080,1920]; % 默认导出1080p视频
            open(v);
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Exporting Video');
            iFrame = 1;
            numFrames = reader.numFrames;

            % 初始化渲染纹理
            [X, Y, Z] = sphere(50);
            frame = readFrame(reader);
            s = surf(ax, X, Y, Z,frame,'FaceColor','texture','EdgeColor','none');

            % 初始化转换对象
            h = hgtransform(ax);
            s.Parent = h;
            warning off;

            % setup camera
            ax.CameraPosition = [0,0,0];
            ax.CameraTarget = [1,0,0];
            ax.CameraUpVector = [0,0,-1];
            ax.CameraViewAngle = 60;

            app.ExportVideoButton.Enable = false;
            while hasFrame(reader)
                d.Value = iFrame/numFrames;

                % Read video data and play
                frame = readFrame(reader);

                % 插值帧的相机参数
                if numEntries(app.outputFrames)
                    idxFrame = round(numFrames*reader.CurrentTime/app.pVideoFileReader.Duration);
                    idxFrame = min(idxFrame,numFrames);
                    ax.CameraPosition = app.outputFrames(idxFrame).posQuery;
                    ax.CameraViewAngle = app.outputFrames(idxFrame).fovQuery;
                    R = app.outputFrames(idxFrame).rotmQuery;

                     if isfield(app.outputFrames(idxFrame),"maskCoord")
                        mask = app.outputFrames(idxFrame).maskCoord;
                        xc = app.outputFrames(idxFrame).xCoord;
                        yc = app.outputFrames(idxFrame).yCoord;
                        zc = app.outputFrames(idxFrame).zCoord;

                        s.XData(mask) = xc;
                        s.YData(mask) = yc;
                        s.ZData(mask) = zc;
                     end
                end

                % 更新图形
                if abs(ax.CameraPosition(1))>1
                    s.CData = frame;
                else
                    s.CData = fliplr(frame);
                end
                h.Matrix(1:3,1:3) = R;

                % pause(1/30);
                F = getframe(fig);
                RGB = frame2im(F);
                RGB = imresize(RGB,outSize);
                writeVideo(v,RGB);
                iFrame = iFrame+1;
            end

            close(d);
            close(v);
            close(fig);
            app.ExportVideoButton.Enable = true; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '360 Panorama Studio (cuixingxing150@gmail.com）';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @mouseDownCallback, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @mouseUpCallback, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mouseMoveCallback, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @scrollWheelCallback, true);
            app.UIAxesProgress = uiaxes(app.UIFigure);
            app.UIAxesProgress.XTick = [];
            app.UIAxesProgress.XTickLabel = '';
            app.UIAxesProgress.YTick = [0 1];
            app.UIAxesProgress.YTickLabel = '';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_play_24.png');
            app.PlayButton.Tooltip = {'Play'};
            app.PlayButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_stop_24.png');
            app.StopButton.Tooltip = {'Stop playing'};
            app.StopButton.Text = '';
            app.RepeatButton = uibutton(app.UIFigure, 'state');
            app.RepeatButton.ValueChangedFcn = createCallbackFcn(app, @RepeatButtonValueChanged, true);
            app.RepeatButton.Tooltip = {'Play in a loop'};
            app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_repeat_off_24.png');
            app.RepeatButton.Text = '';
            app.TimeStatusLabel = uilabel(app.UIFigure);
            app.TimeStatusLabel.Text = 'T = 00:00:00/ 00:00:00';
            app.VideoFileLabel = uilabel(app.UIFigure);
            app.VideoFileLabel.Text = 'Video File';
            app.VideoFileEditField = uieditfield(app.UIFigure, 'text');
            app.VideoFileEditField.ValueChangedFcn = createCallbackFcn(app, @VideoFileEditFieldValueChanged, true);
            app.VideoFileEditField.Placeholder = 'Enter video file path';
            app.VideoFileBrowseButton = uibutton(app.UIFigure, 'push');
            app.VideoFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @VideoFileBrowseButtonPushed, true);
            app.VideoFileBrowseButton.Text = 'Browse';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @progressChangedFcn, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @progressChangingSliderFcn, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'KeyFrame View';
            app.KeyFrameViewDropDown = uidropdown(app.UIFigure);
            app.KeyFrameViewDropDown.Items = {'perspective', 'littleplanet', 'crystalball'};
            app.KeyFrameViewDropDown.ValueChangedFcn = createCallbackFcn(app, @viewModeChanged, true);
            app.KeyFrameViewDropDown.Value = 'perspective';
            app.ExportVideoButton = uibutton(app.UIFigure, 'push');
            app.ExportVideoButton.ButtonPushedFcn = createCallbackFcn(app, @exportVideoFcn, true);
            app.ExportVideoButton.Text = 'Export Video';
            app.AddKeyFrame = uibutton(app.UIFigure, 'push');
            app.AddKeyFrame.ButtonPushedFcn = createCallbackFcn(app, @addKeyFramePushed, true);
            app.AddKeyFrame.Tooltip = {'Add Key Frame'};
            app.AddKeyFrame.Text = '+';
            app.RemoveKeyFrame = uibutton(app.UIFigure, 'push');
            app.RemoveKeyFrame.ButtonPushedFcn = createCallbackFcn(app, @RemoveKeyFramePushed, true);
            app.RemoveKeyFrame.Tooltip = {'Remove Key Frame'};
            app.RemoveKeyFrame.Text = '-';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FreeEditingTab = uitab(app.TabGroup);
            app.FreeEditingTab.Tooltip = {'Mouse drag, scroll wheel to zoom viewpoint'};
            app.FreeEditingTab.Title = 'Free Editing';
            app.UIAxesFreeEditing = uiaxes(app.FreeEditingTab);
            app.UIAxesFreeEditing.XTickLabel = '';
            app.UIAxesFreeEditing.YTickLabel = '';
            app.Preview360Tab = uitab(app.TabGroup);
            app.Preview360Tab.Tooltip = {'Preview 360 Equirectangular Image'};
            app.Preview360Tab.Title = '360 Preview';
            app.UIAxes360Preview = uiaxes(app.Preview360Tab);
            app.UIAxes360Preview.XTick = [];
            app.UIAxes360Preview.YTick = [];
            app.UIAxes360Preview.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoPlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end